classdef fnirs_dataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fNIRSDataViewerUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ChnMaxSpinner            matlab.ui.control.Spinner
        ChnMaxSpinnerLabel       matlab.ui.control.Label
        ChnMinSpinner            matlab.ui.control.Spinner
        ChnMinSpinnerLabel       matlab.ui.control.Label
        HbButtonGroup            matlab.ui.container.ButtonGroup
        HbOButton                matlab.ui.control.RadioButton
        HbRButton                matlab.ui.control.RadioButton
        HbTButton                matlab.ui.control.RadioButton
        PlotTypeButtonGroup      matlab.ui.container.ButtonGroup
        StackedButton            matlab.ui.control.RadioButton
        ButterflyButton          matlab.ui.control.RadioButton
        RemoveBaselineCheckBox   matlab.ui.control.CheckBox
        WavelengthButtonGroup    matlab.ui.container.ButtonGroup
        nmButton_1               matlab.ui.control.RadioButton
        nmButton_2               matlab.ui.control.RadioButton
        DataTypeButtonGroup      matlab.ui.container.ButtonGroup
        HbButton                 matlab.ui.control.RadioButton
        RawButton                matlab.ui.control.RadioButton
        OpenFileButton           matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Raw_Data % Raw loaded data
        Working_Data % Dataset holding data with all processes applied
        Current_Data % Data showing in UIAxes
        hb_Data % data with Beer-Lambert applied
        hdr % header data
        time % time vector for plotting
        Ylim % Ymin and Ymax for raw/working data
        spacing % stacked plot spacing
    end
    
    methods (Access = private)
        
        function results = plotData(app,data,c)
            value = app.RemoveBaselineCheckBox.Value;    
            if value
                data = nirx_offset(data);
            end
            minCh = app.ChnMinSpinner.Value;
            maxCh = app.ChnMaxSpinner.Value;
            chToPlot = minCh:maxCh;
            data = data(:,chToPlot);
            selectedButton = app.PlotTypeButtonGroup.SelectedObject; % plot type
            if strcmpi(selectedButton.Text,'Butterfly')
                plot(app.UIAxes, app.time, data, c);
                app.UIAxes.YLabel.String = 'Amplitude';
            else
                cla(app.UIAxes, 'reset');
                hold(app.UIAxes,"on");
                for line=1:length(chToPlot)
                    plot(app.UIAxes, app.time, (data(:, line))... % insert '*handles.scaling' after line,:) % and take from control on GUI
                        + (line * app.spacing), c);
                end
                hold(app.UIAxes,"off");
                app.UIAxes.YTickLabelMode = "manual";
                app.UIAxes.YTickMode = "manual";
                app.UIAxes.YTick = linspace(app.spacing,length(chToPlot)*app.spacing,length(chToPlot));
                app.UIAxes.YTickLabel = string(1:length(chToPlot));
                app.UIAxes.YLabel.String = 'Channel #';
            end
            axis(app.UIAxes, "tight");
            app.Current_Data = data;
            app.UIAxes.XLabel.String = 'Time (s)';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [file, pth, ~] = uigetfile('*.hdr','Select a file to load');
            [~,filebase,~] = fileparts(file);
            cd(pth);
            app.hdr = nirx_read_hdr([filebase '.hdr']);
            [app.Raw_Data, ~, ~, ~] = nirx_read_wl(filebase,app.hdr);
            app.Ylim = [min(app.Raw_Data(:)) max(app.Raw_Data(:))];
            app.spacing = .2; % hardcode for now. Use max Ymax - Ymin of wl2. Also must change for hb
            app.time = [0:1/app.hdr.sr:(size(app.Raw_Data,2)-1)/app.hdr.sr]';
            app.Working_Data = app.Raw_Data;
            app.Current_Data = squeeze(app.Working_Data(1,:,:));
            app.DataTypeButtonGroup.Enable = "on";
            app.WavelengthButtonGroup.Enable = "on";
            app.RemoveBaselineCheckBox.Enable = "on";
            app.PlotTypeButtonGroup.Enable = "on";
            app.ChnMinSpinner.Limits = [1 app.hdr.nchan]; % using this will need to ensure max >= min + put in separate function
            app.ChnMinSpinner.Value = 1;
            app.ChnMaxSpinner.Limits = [1 app.hdr.nchan];
            app.ChnMaxSpinner.Value = app.hdr.nchan;
            plotData(app,app.Current_Data,'blue');
        end

        % Selection changed function: WavelengthButtonGroup
        function WavelengthButtonGroupSelectionChanged(app, event)
            selectedButton = app.WavelengthButtonGroup.SelectedObject; % make this more generic
            if strcmpi(selectedButton.Text,'760 nm')
                app.Current_Data = squeeze(app.Working_Data(1,:,:));
                plotData(app,app.Current_Data,'blue');
            else
                app.Current_Data = squeeze(app.Working_Data(2,:,:));
                plotData(app,app.Current_Data,'red');
            end
        end

        % Value changed function: RemoveBaselineCheckBox
        function RemoveBaselineCheckBoxValueChanged(app, event)
            value = app.RemoveBaselineCheckBox.Value;
            if value
                app.Working_Data = nirx_offset(app.Working_Data);
                plotData(app,app.Current_Data,'red'); % replot data
            end
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'Raw')
                app.Current_Data = app.Raw_Data;
                app.WavelengthButtonGroup.Enable = "on";
                app.HbButtonGroup.Enable = "off";
            else
                app.HbButtonGroup.Enable = "on";
                app.WavelengthButtonGroup.Enable = "off";
                if isempty(app.hb_Data)
                    % raw intensity to optical density
                    od = nirx_OD(app.Raw_Data);
                    % convert optical measurements to hemoglobin units
                    ec1 = nirx_ecoeff(app.hdr.wl(1));
                    ec2 = nirx_ecoeff(app.hdr.wl(2));
                    ec  = [ec1;ec2];
                    dpf = nirx_DPF([app.hdr.wl(1) app.hdr.wl(2)],20); % change hardcoding of age
                    hbo = zeros(app.hdr.nchan,size(app.Raw_Data,2));
                    hbr = hbo;
                    hbt = hbo;
                    for chn = 1:app.hdr.nchan
                        tmp = squeeze(od(:,:,chn));
                        cdist = app.hdr.dist(chn); % default header distances
                        % MBLL, giving channel distance in cm
                        [hbo(chn,:),hbr(chn,:),hbt(chn,:)] = ...
                            nirx_mbll(tmp,dpf,ec,cdist/10); % Beer-Lambert Law
                    end
                    app.hb_Data.hbo = hbo'; app.hb_Data.hbr = hbr'; app.hb_Data.hbt = hbt';
                end
                app.Current_Data = app.hb_Data.hbo;
                plotData(app,app.Current_Data,'red');
            end
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            
        end

        % Selection changed function: HbButtonGroup
        function HbButtonGroupSelectionChanged(app, event)
            selectedButton = app.HbButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'HbO'
                    app.Current_Data = app.hb_Data.hbo;
                    plotData(app,app.Current_Data,'red');
                case 'HbR'
                    app.Current_Data = app.hb_Data.hbr;
                    plotData(app,app.Current_Data,'blue');
                case 'HbT'
                    app.Current_Data = app.hb_Data.hbt;
                    plotData(app,app.Current_Data,'green');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.fNIRSDataViewerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {557, 557};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fNIRSDataViewerUIFigure = uifigure('Visible', 'off');
            app.fNIRSDataViewerUIFigure.Name = 'fNIRS Data Viewer';
            app.fNIRSDataViewerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.fNIRSDataViewerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenFileButton = uibutton(app.LeftPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.DataTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Enable = 'off';
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.RawButton = uiradiobutton(app.DataTypeButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.HbButton = uiradiobutton(app.DataTypeButtonGroup);
            app.HbButton.Text = 'Hb';
            app.WavelengthButtonGroup = uibuttongroup(app.LeftPanel);
            app.WavelengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WavelengthButtonGroupSelectionChanged, true);
            app.WavelengthButtonGroup.Enable = 'off';
            app.WavelengthButtonGroup.Title = 'Wavelength';
            app.nmButton_2 = uiradiobutton(app.WavelengthButtonGroup);
            app.nmButton_2.Text = '850 nm';
            app.nmButton_1 = uiradiobutton(app.WavelengthButtonGroup);
            app.nmButton_1.Text = '760 nm';
            app.nmButton_1.Value = true;
            app.RemoveBaselineCheckBox = uicheckbox(app.LeftPanel);
            app.RemoveBaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBaselineCheckBoxValueChanged, true);
            app.RemoveBaselineCheckBox.Enable = 'off';
            app.RemoveBaselineCheckBox.Text = 'Remove Baseline';
            app.PlotTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Enable = 'off';
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.ButterflyButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ButterflyButton.Text = 'Butterfly';
            app.ButterflyButton.Value = true;
            app.StackedButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.StackedButton.Text = 'Stacked';
            app.HbButtonGroup = uibuttongroup(app.LeftPanel);
            app.HbButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HbButtonGroupSelectionChanged, true);
            app.HbButtonGroup.Enable = 'off';
            app.HbButtonGroup.Title = 'Hb';
            app.HbTButton = uiradiobutton(app.HbButtonGroup);
            app.HbTButton.Text = 'HbT';
            app.HbRButton = uiradiobutton(app.HbButtonGroup);
            app.HbRButton.Text = 'HbR';
            app.HbOButton = uiradiobutton(app.HbButtonGroup);
            app.HbOButton.Text = 'HbO';
            app.HbOButton.Value = true;
            app.ChnMinSpinnerLabel = uilabel(app.LeftPanel);
            app.ChnMinSpinnerLabel.Text = 'Chn Min';
            app.ChnMinSpinner = uispinner(app.LeftPanel);
            app.ChnMaxSpinnerLabel = uilabel(app.LeftPanel);
            app.ChnMaxSpinnerLabel.Text = 'Chn Max';
            app.ChnMaxSpinner = uispinner(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Intensity')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.fNIRSDataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_dataViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fNIRSDataViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fNIRSDataViewerUIFigure)
        end
    end
end