classdef fnirs_levelOneStats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticsDialog                matlab.ui.Figure
        PlotDesignButton                matlab.ui.control.Button
        InfoTable                       matlab.ui.control.Table
        BaselineConditionDropDown       matlab.ui.control.DropDown
        BaselineConditionDropDownLabel  matlab.ui.control.Label
        LoadEVTFileButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       CallingApp % App calling dialog
       hdr % header
       onsets % condition onsets (if from evt in samples, may need to convert to seconds
       vals % condition values
       npoints % number of samples per channel
       M % Design matrix details, M.X is matrix
    end
    
    methods (Access = private)

        function create_design(app)
            X = [];
            X.basis = 'hrf';
            codes = app.InfoTable.Data.codes;
            durations = app.InfoTable.Data.durs;
            nreps = length([app.hdr.events.code]);
            dur = zeros(1,nreps);
            for code = 1:length(codes)
                cind = find([app.hdr.events.code] == codes(code));
                dur(cind) = durations(code);
            end
            X.dur = dur;
            X.dt = 1/app.hdr.sr;
            X.nsamp = app.npoints;
            X.names = app.InfoTable.Data.names;
            X.values = [app.hdr.events.code];
            X.onsets = [app.hdr.events.samp];
            X.baseline = str2double(app.BaselineConditionDropDown.Value);
            X.implicit = 'no';
            M = nirx_design_matrix(X,0);
            app.M = M;
            save('M.mat','M');
        end
        
        function plotDesign(app)
            imagesc(app.UIAxes,app.M.X);
            colormap(app.UIAxes,gray);
            labels = ['Constant'; app.M.names];
            out(1:2:9)={''}
            out(2:2:8)=names

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hdr, npoints)
            app.CallingApp = caller;
            app.hdr = hdr;
            app.npoints = npoints;
            codes = unique([app.hdr.events.code])';
            app.BaselineConditionDropDown.Items = strsplit(num2str(codes'));
            names = repmat({'Condition Name'},1,length(codes))';
            durs = repmat(20,1,length(codes))';
            t = table(codes,names,durs);
            app.InfoTable.Data = t; % access by ...Data.codes ...Data.names ...Data.durs
        end

        % Button pushed function: LoadEVTFileButton
        function LoadEVTFileButtonPushed(app, event)
            evt_file = uigetfile("*.evt");
            [app.onsets,app.vals] =nirx_read_evt(evt_file);
        end

        % Button pushed function: PlotDesignButton
        function PlotDesignButtonPushed(app, event)
            create_design(app);
            plotDesign(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatisticsDialog = uifigure('Visible', 'off');
            app.StatisticsDialog.Name = '1st Level Statistics';
            app.UIAxes = uiaxes(app.StatisticsDialog);
            title(app.UIAxes, 'Design Matrix')
            xlabel(app.UIAxes, 'Regressors')
            ylabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.LoadEVTFileButton = uibutton(app.StatisticsDialog, 'push');
            app.LoadEVTFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEVTFileButtonPushed, true);
            app.LoadEVTFileButton.Text = 'Load .EVT File';
            app.BaselineConditionDropDownLabel = uilabel(app.StatisticsDialog);
            app.BaselineConditionDropDownLabel.Text = 'Baseline Condition';
            app.BaselineConditionDropDown = uidropdown(app.StatisticsDialog);
            app.BaselineConditionDropDown.Items = {'No Baseline'};
            app.BaselineConditionDropDown.Value = 'No Baseline';
            app.InfoTable = uitable(app.StatisticsDialog);
            app.InfoTable.ColumnName = {'Condition'; 'Name'; 'Duration'};
            app.InfoTable.RowName = {};
            app.InfoTable.ColumnEditable = true;
            app.PlotDesignButton = uibutton(app.StatisticsDialog, 'push');
            app.PlotDesignButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDesignButtonPushed, true);
            app.PlotDesignButton.Text = 'Plot Design';
            % Show the figure after all components are created
            app.StatisticsDialog.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_levelOneStats(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StatisticsDialog)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.StatisticsDialog)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticsDialog)
        end
    end
end